classdef Draft1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        NoneButton                 matlab.ui.control.Button
        ChangeBackgroundLabel      matlab.ui.control.Label
        GreenButton                matlab.ui.control.Button
        BlueButton                 matlab.ui.control.Button
        RedButton                  matlab.ui.control.Button
        Image3                     matlab.ui.control.Image
        Image3_2                   matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        Player2WinsLabel           matlab.ui.control.Label
        Player1WinsLabel           matlab.ui.control.Label
        GameStatusNormalPlayLabel  matlab.ui.control.Label
        Button1                    matlab.ui.control.Button
        NewGameButton              matlab.ui.control.Button
        TTT                        matlab.ui.control.Label
        Button9                    matlab.ui.control.Button
        Button8                    matlab.ui.control.Button
        Button7                    matlab.ui.control.Button
        Button6                    matlab.ui.control.Button
        Button5                    matlab.ui.control.Button
        Button4                    matlab.ui.control.Button
        Button3                    matlab.ui.control.Button
        Button2                    matlab.ui.control.Button
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = public)
        pl_move;
        mat;
        gameStatus;
        selected;
        nextMove;
        p1score;
        p2score;
        p1piece;
        p2piece;
        currPlayerColor;
    end

    
    methods (Access = public)
        
        % FUNCTIONS FOR END GAME POP UP
        % Moves image to board when win and off when in game
        function MoveImage2ButtonPushed(app, ~)
            app.Image2.Position = [-2,129,547,309];
            app.Image3.Position = [107,125,331,100];
        end
        function MoveImage3ButtonPushed(app, ~)
            app.Image3_2.Position = [-2,129,547,309];
            app.Image3.Position = [107,125,331,100];
        end
        function MoveImage3ButtonPushedBack(app, ~)
            app.Image2.Position = [-1000,-1000,547,309];
            app.Image3.Position = [-1000,-1000,547,309];
        end
        function MoveImage2ButtonPushedBack(app, ~)
            app.Image3_2.Position = [-1000,-1000,547,309];
            app.Image3.Position = [-1000,-1000,547,309];
        end


        % ADDED TO END OF EACH BUTTON TO CHECK FOR WIN
        function win(app,m)
             if sum(m(:) ~= -1) >= 6
                app.GameStatusNormalPlayLabel.Text = 'Game Status: Now Move Pieces';
            end
            
            
            %horizontal check
            if (m(1,1)==m(1,2)) && (m(1,2)==m(1,3)) && (m(1,3) ~= -1)
                result=m(1,1);
            elseif (m(2,1)==m(2,2)) && (m(2,2)==m(2,3)) && (m(2,3) ~= -1)
                result=m(2,3);
            elseif (m(3,1)==m(3,2)) && (m(3,2)==m(3,3)) && (m(3,3) ~= -1)
                result=m(3,3);
            %verical check
            elseif (m(1,1)==m(2,1)) && (m(2,1)==m(3,1)) && (m(3,1) ~= -1)
                result=m(3,1);
            elseif (m(1,2)==m(2,2)) && (m(2,2)==m(3,2)) && (m(3,2) ~= -1)
                result=m(3,2);
            elseif (m(1,3)==m(2,3)) && (m(2,3)==m(3,3)) && (m(3,3) ~= -1)
                result=m(3,3);
            %diagonal
            elseif (m(1,1)==m(2,2)) && (m(2,2)==m(3,3)) && (m(3,3) ~= -1)
                result=m(3,3);
            elseif (m(1,3)==m(2,2)) && (m(2,2)==m(3,1)) && (m(3,1) ~= -1)
                result=m(3,1);
            elseif ~ismember(m,-1)
                result=-1;
            else
                result=0;
            end

            % HANDLE UPDATES FOR WIN
            if result==1
                app.TTT.Text='Player 1 Won';
                startupFcn(app);
                app.Player1WinsLabel.Text = strcat(app.Player1WinsLabel.Text, '|');
                app.MoveImage2ButtonPushed();
            elseif result == 2
                app.TTT.Text='Player 2 Won';
                startupFcn(app);
                app.Player2WinsLabel.Text = strcat(app.Player2WinsLabel.Text, '|');
                app.MoveImage3ButtonPushed();
            end
        end
    end
    
    methods (Access = private)


        % PRINT BOARD FOR DEV VIEW AND UPDATE TEXT FOR TURNS
        function status(app, mat)
            if app.pl_move == 1
                disp('Current move: Player 1');
            else
                disp('Current move: Player 2');
            end
       
            disp('Current board:');
            disp(mat);
        end

        % DECIDES WHEN PLAYERS WHEN PLAYERS CAN MOVE PIECE AND NOT PLACE
        function gameStatus = isEarlyGame(app)
            numMoves = sum(app.mat(:) ~= -1);
            
            if numMoves < 6
                gameStatus = true; % Early game
            else
                gameStatus = false; % Not early game
            end
        end

        % USED FOR LATE GAME AND MOVING PIECES
        % Ensures spot is empty and selected is right color for turn
        function valid = selectedIsValid(app)
            valid = false;
            if isequal(app.nextMove, [1,1]) && isequal(app.selected, [1,2])
                valid = true;
            elseif isequal(app.nextMove, [1,1]) && isequal(app.selected, [2,1])
                valid = true;
            elseif isequal(app.nextMove, [1,1]) && isequal(app.selected, [2,2])
                valid = true;

            elseif isequal(app.nextMove, [1,2]) && isequal(app.selected, [1,1])
                valid = true;
            elseif isequal(app.nextMove, [1,2]) && isequal(app.selected, [1,3])
                valid = true;
            elseif isequal(app.nextMove, [1,2]) && isequal(app.selected, [2,1])
                valid = true;
            elseif isequal(app.nextMove, [1,2]) && isequal(app.selected, [2,2])
                valid = true;
            elseif isequal(app.nextMove, [1,2]) && isequal(app.selected, [2,3])
                valid = true;

            elseif isequal(app.nextMove, [1,3]) && isequal(app.selected, [1,2])
                valid = true;
            elseif isequal(app.nextMove, [1,3]) && isequal(app.selected, [2,2])
                valid = true;
            elseif isequal(app.nextMove, [1,3]) && isequal(app.selected, [2,3])
                valid = true;

            elseif isequal(app.nextMove, [2,1]) && isequal(app.selected, [1,1])
                valid = true;
            elseif isequal(app.nextMove, [2,1]) && isequal(app.selected, [1,2])
                valid = true;
            elseif isequal(app.nextMove, [2,1]) && isequal(app.selected, [2,2])
                valid = true;
            elseif isequal(app.nextMove, [2,1]) && isequal(app.selected, [3,1])
               valid = true;
            elseif isequal(app.nextMove, [2,1]) && isequal(app.selected, [3,2])
                valid = true;

            elseif isequal(app.nextMove, [2,2]) && isequal(app.selected, [1,1])
                valid = true;
            elseif isequal(app.nextMove, [2,2]) && isequal(app.selected, [1,2])
                valid = true;
            elseif isequal(app.nextMove, [2,2]) && isequal(app.selected, [1,3])
                valid = true;
            elseif isequal(app.nextMove, [2,2]) && isequal(app.selected, [2,1])
                valid = true;
            elseif isequal(app.nextMove, [2,2]) && isequal(app.selected, [2,3])
                valid = true;
            elseif isequal(app.nextMove, [2,2]) && isequal(app.selected, [3,1])
                valid = true;
            elseif isequal(app.nextMove, [2,2]) && isequal(app.selected, [3,2])
                valid = true;
            elseif isequal(app.nextMove, [2,2]) && isequal(app.selected, [3,3])
                valid = true;

            elseif isequal(app.nextMove, [2,3]) && isequal(app.selected, [1,2])
                valid = true;
            elseif isequal(app.nextMove, [2,3]) && isequal(app.selected, [1,3])
                valid = true;
            elseif isequal(app.nextMove, [2,3]) && isequal(app.selected, [2,2])
                valid = true;
            elseif isequal(app.nextMove, [2,3]) && isequal(app.selected, [3,2])
                valid = true;
            elseif isequal(app.nextMove, [2,3]) && isequal(app.selected, [3,3])
                valid = true;

            elseif isequal(app.nextMove, [3,1]) && isequal(app.selected, [2,1])
                valid = true;
            elseif isequal(app.nextMove, [3,1]) && isequal(app.selected, [2,2])
                valid = true;
            elseif isequal(app.nextMove, [3,1]) && isequal(app.selected, [3,2])
                valid = true;

            elseif isequal(app.nextMove, [3,2]) && isequal(app.selected, [2,1])
                valid = true;
            elseif isequal(app.nextMove, [3,2]) && isequal(app.selected, [2,2])
                valid = true;
            elseif isequal(app.nextMove, [3,2]) && isequal(app.selected, [2,3])
                valid = true;
            elseif isequal(app.nextMove, [3,2]) && isequal(app.selected, [3,1])
                valid = true;
            elseif isequal(app.nextMove, [3,2]) && isequal(app.selected, [3,3])
                valid = true;

            elseif isequal(app.nextMove, [3,3]) && isequal(app.selected, [2,2])
                valid = true;
            elseif isequal(app.nextMove, [3,3]) && isequal(app.selected, [2,3])
                valid = true;
            elseif isequal(app.nextMove, [3,3]) && isequal(app.selected, [3,2])
                valid = true;
            end


        end 

        % SETS PRIOR MOVE TO BLANK SPOT
        function updateSelected(app) 
            if isequal(app.selected, [1,1])
                app.Button1.Text='';
                app.mat(1,1)=-1;
            elseif isequal(app.selected, [1,2])
                app.Button2.Text='';
                app.mat(1,2)=-1;
            elseif isequal(app.selected, [1,3])
                app.Button3.Text='';
                app.mat(1,3)=-1;
            elseif isequal(app.selected, [2,1])
                app.Button4.Text='';
                app.mat(2,1)=-1;
            elseif isequal(app.selected, [2,2])
                app.Button5.Text='';
                app.mat(2,2)=-1;
            elseif isequal(app.selected, [2,3])
                app.Button6.Text='';
                app.mat(2,3)=-1;
            elseif isequal(app.selected, [3,1])
                app.Button7.Text='';
                app.mat(3,1)=-1;
            elseif isequal(app.selected, [3,2])
                app.Button8.Text='';
                app.mat(3,2)=-1;
            elseif isequal(app.selected, [3,3])
                app.Button9.Text='';
                app.mat(3,3)=-1;
            end
            app.selected = [0,0];
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Button1.Enable='on';
            app.Button2.Enable='on';
            app.Button3.Enable='on';
            app.Button4.Enable='on';
            app.Button5.Enable='on';
            app.Button6.Enable='on';
            app.Button7.Enable='on';
            app.Button8.Enable='on';
            app.Button9.Enable='on';
            app.p1piece = '🔴';
            app.p2piece = '🔵';
            app.currPlayerColor='p1';
            app.pl_move=1;
            app.gameStatus = true;
            app.selected = [0,0];
            imgPos = app.Image.Position;
            imgPos(1) = -1000; 
            imgPos(2) = -1000; 
            app.Image.Position = imgPos;

        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            app.MoveImage2ButtonPushedBack();
            app.MoveImage3ButtonPushedBack();
            app.Button1.Enable='on';
            app.Button2.Enable='on';
            app.Button3.Enable='on';
            app.Button4.Enable='on';
            app.Button5.Enable='on';
            app.Button6.Enable='on';
            app.Button7.Enable='on';
            app.Button8.Enable='on';
            app.Button9.Enable='on';
            app.pl_move=1;
            app.mat=(-1)*ones(3,3);
            app.TTT.Text='Go Player 1';
            app.Button1.Text='';
            app.Button2.Text='';
            app.Button3.Text='';
            app.Button4.Text='';
            app.Button5.Text='';
            app.Button6.Text='';
            app.Button7.Text='';
            app.Button8.Text='';
            app.Button9.Text='';

        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            % COPIED TO ALL 9 BUTTONS
            % PSEUDO CODE
            
            % if early game
            %   if spot empty
            %       place piece for player turn
            % 
            % else if late game
            %   if this spot empty
            %       if prior selected piece is valid 
            %           set old piece to null 
            %           set this spot to piece
            %   else if this spot taken
            %       set selected to this position for movement in next button
            % end
            % 
            % Check for win condition
            % Print updated board


            if app.isEarlyGame() 
                if app.pl_move==1 && app.mat(1,1) == -1
                    app.TTT.Text='Go Player 2';
                    app.pl_move=2; 
                    app.Button1.Text=app.p1piece;
                    app.mat(1,1)=1;
                elseif app.mat(1,1) == -1
                    app.TTT.Text='Go Player 1';
                    app.pl_move=1; 
                    app.Button1.Text= app.p2piece;
                    app.mat(1,1)=2; 
                end
            else
                app.nextMove = [1,1];
                if app.selectedIsValid() && app.mat(1,1) == -1 % CHECKS OLD MOVE TO THIS BUTTON AND IS EMPTY
                    % make the move
                    if app.pl_move==1 && app.mat(app.selected(1), app.selected(2)) == 1
                        app.TTT.Text='Go Player 2';
                        app.pl_move=2; 
                        app.Button1.Text= app.p1piece;
                        app.mat(1,1)=1; 
                        app.updateSelected();
                    elseif app.mat(app.selected(1), app.selected(2)) == 2
                        app.TTT.Text='Go Player 1';
                        app.pl_move=1; 
                        app.Button1.Text= app.p2piece;
                        app.mat(1,1)=2; 
                        app.updateSelected();
                    end
                else
                    app.selected = [1,1]; 
                end
            end
            win(app, app.mat);
            status(app, app.mat);
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            if app.isEarlyGame()
                if app.pl_move==1 && app.mat(1,2) == -1
                    app.TTT.Text='Go Player 2';
                    app.pl_move=2; %now it is player 2's turn
                    app.Button2.Text = app.p1piece;
                    app.mat(1,2)=1; %player 1 pick (1,1) of matrix mat
                elseif app.mat(1,2) == -1
                    app.TTT.Text='Go Player 1';
                    app.pl_move=1; %now it is player 2's turn
                    app.Button2.Text=app.p2piece;
                    app.mat(1,2)=2; %player 1 pick (1,1) of matrix mat
                end
            else
                app.nextMove = [1,2];
                if app.selectedIsValid() && app.mat(1,2) == -1 % CHECKS OLD MOVE TO THIS BUTTON AND IS EMPTY
                    % make the move
                    if app.pl_move==1 && app.mat(app.selected(1), app.selected(2)) == 1
                        app.TTT.Text='Go Player 2';
                        app.pl_move=2; %now it is player 2's turn
                        app.Button2.Text=app.p1piece;
                        app.mat(1,2)=1; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    elseif app.mat(app.selected(1), app.selected(2)) == 2
                        app.TTT.Text='Go Player 1';
                        app.pl_move=1; %now it is player 2's turn
                        app.Button2.Text=app.p2piece;
                        app.mat(1,2)=2; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    end
                else
                    app.selected = [1,2]; % make new selection
                end
            end
            win(app, app.mat);
            status(app, app.mat);
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            if app.isEarlyGame()
                if app.pl_move==1 && app.mat(1,3) == -1
                    app.TTT.Text='Go Player 2';
                    app.pl_move=2; %now it is player 2's turn
                    app.Button3.Text=app.p1piece;
                    app.mat(1,3)=1; %player 1 pick (1,1) of matrix mat
                    app.updateSelected();
                elseif app.mat(1,3) == -1
                    app.TTT.Text='Go Player 1';
                    app.pl_move=1; %now it is player 2's turn
                    app.Button3.Text=app.p2piece;
                    app.mat(1,3)=2; %player 1 pick (1,1) of matrix mat
                    app.updateSelected();
                end
            else
                app.nextMove = [1,3];
                if app.selectedIsValid() && app.mat(1,3) == -1% CHECKS OLD MOVE TO THIS BUTTON AND IS EMPTY
                    % make the move
                    if app.pl_move==1 && app.mat(app.selected(1), app.selected(2)) == 1
                        app.TTT.Text='Go Player 2';
                        app.pl_move=2; %now it is player 2's turn
                        app.Button3.Text=app.p1piece;
                        app.mat(1,3)=1; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    elseif app.mat(app.selected(1), app.selected(2)) == 2
                        app.TTT.Text='Go Player 1';
                        app.pl_move=1; %now it is player 2's turn
                        app.Button3.Text=app.p2piece;
                        app.mat(1,3)=2; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    end
                else
                    app.selected = [1,3]; % make new selection
                end
            end
            win(app, app.mat);
            status(app, app.mat);
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
            if app.isEarlyGame()
                if app.pl_move==1 && app.mat(2,1) == -1
                    app.TTT.Text='Go Player 2';
                    app.pl_move=2; %now it is player 2's turn
                    app.Button4.Text=app.p1piece;
                    app.mat(2,1)=1; %player 1 pick (1,1) of matrix mat
                elseif app.mat(2,1) == -1
                    app.TTT.Text='Go Player 1';
                    app.pl_move=1; %now it is player 2's turn
                    app.Button4.Text=app.p2piece;
                    app.mat(2,1)=2; %player 1 pick (1,1) of matrix mat
                end
            else
                app.nextMove = [2,1];
                if app.selectedIsValid() && app.mat(2,1) == -1% CHECKS OLD MOVE TO THIS BUTTON AND IS EMPTY
                    % make the move
                    if app.pl_move==1 && app.mat(app.selected(1), app.selected(2)) == 1
                        app.TTT.Text='Go Player 2';
                        app.pl_move=2; %now it is player 2's turn
                        app.Button4.Text=app.p1piece;
                        app.mat(1,1)=1; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    elseif app.mat(app.selected(1), app.selected(2)) == 2
                        app.TTT.Text='Go Player 1';
                        app.pl_move=1; %now it is player 2's turn
                        app.Button4.Text=app.p2piece;
                        app.mat(2,1)=2; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    end
                else
                    app.selected = [2,1]; % make new selection
                end
            end
            win(app, app.mat);
            status(app, app.mat);
        end

        % Button pushed function: Button5
        function Button5Pushed(app, event)
            if app.isEarlyGame()
                if app.pl_move==1 && app.mat(2,2) == -1
                    app.TTT.Text='Go Player 2';
                    app.pl_move=2; %now it is player 2's turn
                    app.Button5.Text=app.p1piece;
                    app.mat(2,2)=1; %player 1 pick (1,1) of matrix mat
                elseif app.mat(2,2) == -1
                    app.TTT.Text='Go Player 1';
                    app.pl_move=1; %now it is player 2's turn
                    app.Button5.Text=app.p2piece;
                    app.mat(2,2)=2; %player 1 pick (1,1) of matrix mat
                end
            else
                app.nextMove = [2,2];
                if app.selectedIsValid() && app.mat(2,2) == -1% CHECKS OLD MOVE TO THIS BUTTON AND IS EMPTY
                    % make the move
                    if app.pl_move==1 && app.mat(app.selected(1), app.selected(2)) == 1
                        app.TTT.Text='Go Player 2';
                        app.pl_move=2; %now it is player 2's turn
                        app.Button5.Text=app.p1piece;
                        app.mat(2,2)=1; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    elseif app.mat(app.selected(1), app.selected(2)) == 2
                        app.TTT.Text='Go Player 1';
                        app.pl_move=1; %now it is player 2's turn
                        app.Button5.Text=app.p2piece;
                        app.mat(2,2)=2; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    end
                else
                    app.selected = [2,2]; % make new selection
                end
            end
            win(app, app.mat);
            status(app, app.mat);
        end

        % Button pushed function: Button6
        function Button6Pushed(app, event)
            if app.isEarlyGame()
                if app.pl_move==1 && app.mat(2,3) == -1
                    app.TTT.Text='Go Player 2';
                    app.pl_move=2; %now it is player 2's turn
                    app.Button6.Text=app.p1piece;
                    app.mat(2,3)=1; %player 1 pick (1,1) of matrix mat
                elseif app.mat(2,3) == -1
                    app.TTT.Text='Go Player 1';
                    app.pl_move=1; %now it is player 2's turn
                    app.Button6.Text=app.p2piece;
                    app.mat(2,3)=2; %player 1 pick (1,1) of matrix mat
                end
            else
                app.nextMove = [2,3];
                if app.selectedIsValid() && app.mat(2,3) == -1% CHECKS OLD MOVE TO THIS BUTTON AND IS EMPTY
                    % make the move
                    if app.pl_move==1 && app.mat(app.selected(1), app.selected(2)) == 1
                        app.TTT.Text='Go Player 2';
                        app.pl_move=2; %now it is player 2's turn
                        app.Button6.Text=app.p1piece;
                        app.mat(2,3)=1; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    elseif app.mat(app.selected(1), app.selected(2)) == 2
                        app.TTT.Text='Go Player 1';
                        app.pl_move=1; %now it is player 2's turn
                        app.Button6.Text=app.p2piece;
                        app.mat(2,3)=2; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    end
                else
                    app.selected = [2,3]; % make new selection
                end
            end
            win(app, app.mat);
            status(app, app.mat);
        end

        % Button pushed function: Button7
        function Button7Pushed(app, event)
            if app.isEarlyGame()
                if app.pl_move==1 && app.mat(3,1) == -1
                    app.TTT.Text='Go Player 2';
                    app.pl_move=2; %now it is player 2's turn
                    app.Button7.Text=app.p1piece;
                    app.mat(3,1)=1; %player 1 pick (1,1) of matrix mat
                elseif app.mat(3,1) == -1
                    app.TTT.Text='Go Player 1';
                    app.pl_move=1; %now it is player 2's turn
                    app.Button7.Text=app.p2piece;
                    app.mat(3,1)=2; %player 1 pick (1,1) of matrix mat
                end
            else
                app.nextMove = [3,1];
                if app.selectedIsValid() && app.mat(3,1) == -1% CHECKS OLD MOVE TO THIS BUTTON AND IS EMPTY
                    % make the move
                    if app.pl_move==1 && app.mat(app.selected(1), app.selected(2)) == 1
                        app.TTT.Text='Go Player 2';
                        app.pl_move=2; %now it is player 2's turn
                        app.Button7.Text=app.p1piece;
                        app.mat(3,1)=1; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    elseif app.mat(app.selected(1), app.selected(2)) == 2
                        app.TTT.Text='Go Player 1';
                        app.pl_move=1; %now it is player 2's turn
                        app.Button7.Text=app.p2piece;
                        app.mat(3,1)=2; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    end
                else
                    app.selected = [3,1]; % make new selection
                end
            end
            status(app, app.mat);
            win(app, app.mat);

        end

        % Button pushed function: Button8
        function Button8Pushed(app, event)
            if app.isEarlyGame()
                if app.pl_move==1 && app.mat(3,2) == -1
                    app.TTT.Text='Go Player 2';
                    app.pl_move=2; %now it is player 2's turn
                    app.Button8.Text=app.p1piece;
                    app.mat(3,2)=1; %player 1 pick (1,1) of matrix mat
                elseif app.mat(3,2) == -1
                    app.TTT.Text='Go Player 1';
                    app.pl_move=1; %now it is player 2's turn
                    app.Button8.Text=app.p2piece;
                    app.mat(3,2)=2; %player 1 pick (1,1) of matrix mat
                end
            else
                app.nextMove = [3,2];
                if app.selectedIsValid() && app.mat(3,2) == -1% CHECKS OLD MOVE TO THIS BUTTON AND IS EMPTY
                    % make the move
                    if app.pl_move==1 && app.mat(app.selected(1), app.selected(2)) == 1
                        app.TTT.Text='Go Player 2';
                        app.pl_move=2; %now it is player 2's turn
                        app.Button8.Text=app.p1piece;
                        app.mat(3,2)=1; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    elseif app.mat(app.selected(1), app.selected(2)) == 2
                        app.TTT.Text='Go Player 1';
                        app.pl_move=1; %now it is player 2's turn
                        app.Button8.Text=app.p2piece;
                        app.mat(3,2)=2; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    end
                else
                    app.selected = [3,2]; % make new selection
                end
            end
            win(app, app.mat);
            status(app, app.mat);
        end

        % Button pushed function: Button9
        function Button9Pushed(app, event)
            if app.isEarlyGame()
                if app.pl_move==1 && app.mat(3,3) == -1
                    app.TTT.Text='Go Player 2';
                    app.pl_move=2; %now it is player 2's turn
                    app.Button9.Text=app.p1piece;
                    app.mat(3,3)=1; %player 1 pick (1,1) of matrix mat
                elseif app.mat(3,3) == -1
                    app.TTT.Text='Go Player 1';
                    app.pl_move=1; %now it is player 2's turn
                    app.Button9.Text=app.p2piece;
                    app.mat(3,3)=2; %player 1 pick (1,1) of matrix mat
                end
            else
                app.nextMove = [3,3];
                if app.selectedIsValid() && app.mat(3,3) == -1% CHECKS OLD MOVE TO THIS BUTTON AND IS EMPTY
                    % make the move
                    if app.pl_move==1 && app.mat(app.selected(1), app.selected(2)) == 1
                        app.TTT.Text='Go Player 2';
                        app.pl_move=2; %now it is player 2's turn
                        app.Button9.Text=app.p1piece;
                        app.mat(3,3)=1; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    elseif app.mat(app.selected(1), app.selected(2)) == 2
                        app.TTT.Text='Go Player 1';
                        app.pl_move=1; %now it is player 2's turn
                        app.Button9.Text=app.p2piece;
                        app.mat(3,3)=2; %player 1 pick (1,1) of matrix mat
                        app.updateSelected();
                    end
                else
                    app.selected = [3,3]; % make new selection
                end
            end
            win(app, app.mat);
            status(app, app.mat);
        end

        % Button pushed function: GreenButton
        function GreenButtonPushed(app, event)
            app.UIFigure.Color = [0, 1, 0];
        end

        % Button pushed function: RedButton
        function RedButtonPushed(app, event)
            app.UIFigure.Color = [1, 0, 0];
        end

        % Button pushed function: BlueButton
        function BlueButtonPushed(app, event)
            app.UIFigure.Color = [0, 0, 1];
        end

        % Button pushed function: NoneButton
        function NoneButtonPushed(app, event)
            app.UIFigure.Color = [.65, .65, .65];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'board2.png');
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = '.';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = '.';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Text = '.';
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button5.Text = '.';
            app.Button6 = uibutton(app.UIFigure, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button6.Text = '.';
            app.Button7 = uibutton(app.UIFigure, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @Button7Pushed, true);
            app.Button7.Text = '.';
            app.Button8 = uibutton(app.UIFigure, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @Button8Pushed, true);
            app.Button8.Text = '.';
            app.Button9 = uibutton(app.UIFigure, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @Button9Pushed, true);
            app.Button9.Text = '.';
            app.TTT = uilabel(app.UIFigure);
            app.TTT.Text = 'TIK-TAK-TOE GAME';
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.Button1 = uibutton(app.UIFigure, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = '.';
            app.GameStatusNormalPlayLabel = uilabel(app.UIFigure);
            app.GameStatusNormalPlayLabel.Text = 'Game Status: Normal Play';
            app.Player1WinsLabel = uilabel(app.UIFigure);
            app.Player1WinsLabel.Text = 'Player 1 Wins: ';
            app.Player2WinsLabel = uilabel(app.UIFigure);
            app.Player2WinsLabel.Text = 'Player 2 Wins: ';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'player1.gif');
            app.Image3_2 = uiimage(app.UIFigure);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'player2.gif');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'goodgame.png');
            app.RedButton = uibutton(app.UIFigure, 'push');
            app.RedButton.ButtonPushedFcn = createCallbackFcn(app, @RedButtonPushed, true);
            app.RedButton.Text = 'Red';
            app.BlueButton = uibutton(app.UIFigure, 'push');
            app.BlueButton.ButtonPushedFcn = createCallbackFcn(app, @BlueButtonPushed, true);
            app.BlueButton.Text = 'Blue';
            app.GreenButton = uibutton(app.UIFigure, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenButtonPushed, true);
            app.GreenButton.Text = 'Green';
            app.ChangeBackgroundLabel = uilabel(app.UIFigure);
            app.ChangeBackgroundLabel.Text = 'Change Background';
            app.NoneButton = uibutton(app.UIFigure, 'push');
            app.NoneButton.ButtonPushedFcn = createCallbackFcn(app, @NoneButtonPushed, true);
            app.NoneButton.Text = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Draft1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end